classdef ImageProcessingApplication1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IPApplicationUIFigure  matlab.ui.Figure
        APPLabel               matlab.ui.control.Label
        TabGroup               matlab.ui.container.TabGroup
        Tab_2                  matlab.ui.container.Tab
        TextArea_3             matlab.ui.control.TextArea
        Label_26               matlab.ui.control.Label
        Label_18               matlab.ui.control.Label
        Label_8                matlab.ui.control.Label
        TextArea_2             matlab.ui.control.TextArea
        Label_7                matlab.ui.control.Label
        TextArea               matlab.ui.control.TextArea
        Label_6                matlab.ui.control.Label
        gammaSlider            matlab.ui.control.Slider
        DropDown_2             matlab.ui.control.DropDown
        Label_5                matlab.ui.control.Label
        DropDown               matlab.ui.control.DropDown
        Label_3                matlab.ui.control.Label
        Label_27               matlab.ui.control.Label
        Label_2                matlab.ui.control.Label
        Label                  matlab.ui.control.Label
        Slider_2               matlab.ui.control.Slider
        Button_12              matlab.ui.control.Button
        Button_11              matlab.ui.control.Button
        Button                 matlab.ui.control.Button
        Label_4                matlab.ui.control.Label
        UIAxes_12              matlab.ui.control.UIAxes
        UIAxes_10              matlab.ui.control.UIAxes
        UIAxes                 matlab.ui.control.UIAxes
        UIAxes_2               matlab.ui.control.UIAxes
        Tab                    matlab.ui.container.Tab
        TextArea_4             matlab.ui.control.TextArea
        TextArea_4Label        matlab.ui.control.Label
        Slider_4               matlab.ui.control.Slider
        ListBox_3              matlab.ui.control.ListBox
        ListBox_2              matlab.ui.control.ListBox
        Slider_3               matlab.ui.control.Slider
        Label_15               matlab.ui.control.Label
        Label_14               matlab.ui.control.Label
        Label_13               matlab.ui.control.Label
        Button_6               matlab.ui.control.Button
        Label_17               matlab.ui.control.Label
        Button_5               matlab.ui.control.Button
        Button_4               matlab.ui.control.Button
        Label_16               matlab.ui.control.Label
        Label_12               matlab.ui.control.Label
        Button_3               matlab.ui.control.Button
        UIAxes_7               matlab.ui.control.UIAxes
        UIAxes_6               matlab.ui.control.UIAxes
        UIAxes_5               matlab.ui.control.UIAxes
        Tab_3                  matlab.ui.container.Tab
        Label_21               matlab.ui.control.Label
        Button_8               matlab.ui.control.Button
        ListBox_4              matlab.ui.control.ListBox
        Label_20               matlab.ui.control.Label
        Label_11               matlab.ui.control.Label
        Button_7               matlab.ui.control.Button
        ListBox                matlab.ui.control.ListBox
        Label_19               matlab.ui.control.Label
        Label_28               matlab.ui.control.Label
        Button_14              matlab.ui.control.Button
        Button_13              matlab.ui.control.Button
        Button_2               matlab.ui.control.Button
        Label_10               matlab.ui.control.Label
        Label_9                matlab.ui.control.Label
        UIAxes_13              matlab.ui.control.UIAxes
        UIAxes_11              matlab.ui.control.UIAxes
        UIAxes_9               matlab.ui.control.UIAxes
        UIAxes_8               matlab.ui.control.UIAxes
        Tab_4                  matlab.ui.container.Tab
        EditField              matlab.ui.control.NumericEditField
        Label_24               matlab.ui.control.Label
        Button_10              matlab.ui.control.Button
        ListBox_5              matlab.ui.control.ListBox
        Label_23               matlab.ui.control.Label
        Label_22               matlab.ui.control.Label
        Button_9               matlab.ui.control.Button
        UIAxes_15              matlab.ui.control.UIAxes
        UIAxes_14              matlab.ui.control.UIAxes
        Tab_5                  matlab.ui.container.Tab
        Label_32               matlab.ui.control.Label
        Label_31               matlab.ui.control.Label
        Button_18              matlab.ui.control.Button
        Button_17              matlab.ui.control.Button
        Label_30               matlab.ui.control.Label
        Label_29               matlab.ui.control.Label
        Button_16              matlab.ui.control.Button
        Button_15              matlab.ui.control.Button
        ListBox_6              matlab.ui.control.ListBox
        UIAxes_17              matlab.ui.control.UIAxes
        UIAxes_16              matlab.ui.control.UIAxes
        Tab_6                  matlab.ui.container.Tab
        EditField_2            matlab.ui.control.NumericEditField
        Label_37               matlab.ui.control.Label
        Label_36               matlab.ui.control.Label
        Label_35               matlab.ui.control.Label
        Label_33               matlab.ui.control.Label
        Button_20              matlab.ui.control.Button
        Button_19              matlab.ui.control.Button
        ListBox_7              matlab.ui.control.ListBox
        UIAxes_19              matlab.ui.control.UIAxes
        UIAxes_18              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        inputImg='' % Description
        outputImg % Description
        camera % Description
        switchValue = 0 % Description
        sliderValue % Description
        contrastChangingValue % Description
        tempImg % Description
        motion %


        inputImg2 % Description
        outputImg2 % Description
        switchValue2 = 0 % Description

        KMValue = 1 % Description
        CMValue = 1 % Description


        inputImg3 % Description
        outputImg3 % Description
        switchValue3 = 0% Description
        middleImg % Description


        inputImg4 % Description
        outputImg4 % Description
        switchValue4 = 0 % Description
        
        inputImg5
        
        pollute=0 % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function SelectInputStyle(app, event)
            [file, path] = uigetfile('*.*');
            app.inputImg = NaN;
            if isequal(file,0)
                figure(app.IPApplicationUIFigure);
                return;
            end
            figure(app.IPApplicationUIFigure);
            app.inputImg = imread(fullfile(path,file));
            imshow(app.inputImg,'parent',app.UIAxes);
        end

        % Callback function
        function SwitchValue(app, event)

        end

        % Callback function
        function SelectFilterStyle(app, event)
            if isequal(app.sliderValue,1)
                %img_double = im2double(app.inputImg);
                %gray_image = rgb2gray(app.inputImg);
                app.outputImg = imadjust(app.inputImg,[0.3 0.7],[]);
                imshow(app.outputImg,'parent',app.UIAxes_2);
            elseif isequal(app.sliderValue,2)          
                app.outputImg = imbalanced(app.inputImg,'ColorSpace','rgb','Ratio',[1 1 1]);
                imshow(app.outputImg,'parent',app.UIAxes_2);
            elseif isequal(app.sliderValue,3)
                
            end

        end

        % Value changing function: Slider_2
        function Contrast(app, event)
            changingValue = event.Value;
            if isempty(app.inputImg)
                app.TextArea_3.Value = "请先输入图片";
                return
            end
            app.TextArea_3.Value = "请选择图像增强方法";
            gray = rgb2gray(app.inputImg);
            FFT = fftshift(fft2(gray));
            F=real(FFT);
            T=log(F+1);
            imshow(T,[],'parent',app.UIAxes_12);
            imshow(gray,[],'parent',app.UIAxes_10);

            if strcmp(app.DropDown.Value,'对比度')
                app.outputImg = imadjust(app.inputImg,[0.2 changingValue],[0 1]);
                app.tempImg = imadjust(app.inputImg,[0.2 changingValue],[0 1]);
                app.TextArea.Value = "对比度大小";
            elseif strcmp(app.DropDown.Value,'色调')
                changingValue = event.Value;
                img_hsv = rgb2hsv(app.inputImg); 
                img_hsv(:,:,2) = img_hsv(:,:,2) * changingValue;
                app.outputImg = hsv2rgb(img_hsv);
                app.TextArea.Value = "色调强度";
            elseif strcmp(app.DropDown.Value,'饱和度')
                changingValue = event.Value;
                img_hsv = rgb2hsv(app.inputImg);
                img_hsv(:,:,1) = img_hsv(:,:,1) + changingValue;
                app.outputImg = hsv2rgb(img_hsv);
                app.TextArea.Value = "饱和度大小";
            elseif strcmp(app.DropDown.Value,'亮度')
                changingValue = event.Value;
                img_hsv = rgb2hsv(app.inputImg);
                img_hsv(:,:,3) = img_hsv(:,:,3) * changingValue;
                app.outputImg = hsv2rgb(img_hsv);
                app.TextArea.Value = "亮度大小";
            end
            imshow(app.outputImg,'parent',app.UIAxes_2);
        end

        % Callback function
        function Saturation(app, event)
            changingValue = event.Value;
            img_hsv = rgb2hsv(app.inputImg); 
            img_hsv(:,:,2) = img_hsv(:,:,2) * changingValue;
            app.outputImg = hsv2rgb(img_hsv);
            imshow(app.outputImg,'parent',app.UIAxes_2);
        end

        % Callback function
        function Hue(app, event)
            changingValue = event.Value;
            img_hsv = rgb2hsv(app.inputImg);
            img_hsv(:,:,1) = img_hsv(:,:,1) + changingValue;
            app.outputImg = hsv2rgb(img_hsv);
            imshow(app.outputImg,'parent',app.UIAxes_2);
        end

        % Callback function
        function Brightness(app, event)
            changingValue = event.Value;
            img_hsv = rgb2hsv(app.inputImg);
            img_hsv(:,:,3) = img_hsv(:,:,3) * changingValue;
            app.outputImg = hsv2rgb(img_hsv);
            imshow(app.outputImg,'parent',app.UIAxes_2);
        end

        % Button pushed function: Button_11
        function ResetInput(app, event)
            app.TextArea_3.Value = "请先输入图片";
            cla(app.UIAxes);
            cla(app.UIAxes_10);
            cla(app.UIAxes_2);
            cla(app.UIAxes_12);
            app.inputImg = '';
            app.outputImg = '';
        end

        % Button pushed function: Button_12
        function SaveOutput(app, event)
            [file, path] = uiputfile({'*.jpg;*.tif;*.png;*.gif','All Image Files';...
            '*.*','All Files' },'Save Image As');
            if isequal(file,0) || isequal(path,0)
                disp('User cancelled the save operation.')
            else
                imwrite(app.outputImg, fullfile(path,file));
            end
        end

        % Callback function
        function SelectInputStyle2(app, event)
            if isequal(app.switchValue2,0)
                app.camera = webcam(1);
                app.camera.Resolution = '1280x720';

                figure(app.IPApplicationUIFigure);
                
                for c = 1:6
                    app.inputImg2 = snapshot(app.camera);
                end
                
                imshow(app.inputImg2,'parent',app.UIAxes_4);

                clear('app.camera');
            else            
                [file, path] = uigetfile('*.*');

                if isequal(file,0)
                    figure(app.IPApplicationUIFigure);
                    return;
                end
                figure(app.IPApplicationUIFigure);
                app.inputImg2 = imread(fullfile(path,file));
                imshow(app.inputImg2,'parent',app.UIAxes_4);
            end
        end

        % Callback function
        function SwitchValue2(app, event)
            value = app.Switch_2.Value;
            
            if strcmp(value,'Browse')
                app.switchValue2 = 1;
            else
                app.switchValue2 = 0;
            end
        end

        % Callback function
        function SaveOutput2(app, event)
            [file, path] = uiputfile({'*.jpg;*.tif;*.png;*.gif','All Image Files';...
            '*.*','All Files' },'Save Image As');
            if isequal(file,0) || isequal(path,0)
                disp('User cancelled the save operation.')
            else
                imwrite(app.outputImg2, fullfile(path,file));
            end
        end

        % Callback function
        function ResetInput2(app, event)
            cla(app.UIAxes_4);
            cla(app.UIAxes_3);
         
            app.inputImg2 = '';
            app.outputImg2 = '';
            clear('cam');
        end

        % Callback function
        function EdgeDetection(app, event)
            value_ED = app.EdgeDetectionButton.Value;
            
            
            if isequal(value_ED,1) 
                app.ThresholdingButton.Value = 0;

                app.ChooseThresholdMethodLabel.Visible = 'off';
                app.MeanButton_2.Visible = 'off';
                app.OtsuButton.Visible = 'off';
                app.AdaptiveThresholdingButton.Visible = 'off';
                app.FixedThresholdingButton.Value = 0;
                app.FixedThresholdingButton.Visible = 'off';
                app.ChooseValueForTheThresholdEditFieldLabel.Visible = 'off';
                app.ChooseValueForTheThresholdEditField.Visible = 'off';

                app.ClusteringButton.Value = 0;

                app.ChooseClusteringMethodLabel.Visible = 'off';
                app.KmeansButton.Visible = 'off';
                app.FuzzyCmeansButton.Visible = 'off';
                app.ChooseKmeanKnob.Visible = 'off';
                app.ApplyButton.Visible = 'off';
                app.ChooseKmeanKnobLabel.Visible = 'off';
                app.ChooseCmeanKnob.Visible = "off";
                app.ApplyButton_2.Visible = 'off';
                app.ChooseCmeanKnobLabel.Visible = 'off';
            end

            if isequal(value_ED,1)
                app.ChooseEdgeDetectionMethodLabel.Visible ="on";
                app.CannyButton.Visible = "on";
                app.SobelButton.Visible = "on";
                app.PrewittButton.Visible = "on";
                app.RobertsButton.Visible = "on";
                app.LaplacianofGaussianButton.Visible = "on";
            elseif isequal(value_ED,0)
                app.ChooseEdgeDetectionMethodLabel.Visible ="off";
                app.CannyButton.Visible = "off";
                app.SobelButton.Visible = "off";
                app.PrewittButton.Visible = "off";
                app.RobertsButton.Visible = "off";
                app.LaplacianofGaussianButton.Visible = "off";
            end

            
        end

        % Callback function
        function Thresholding(app, event)
            value_TH = app.ThresholdingButton.Value;
            
            if isequal(value_TH,1) 
                app.EdgeDetectionButton.Value = 0;

                app.ChooseEdgeDetectionMethodLabel.Visible ="off";
                app.CannyButton.Visible = "off";
                app.SobelButton.Visible = "off";
                app.PrewittButton.Visible = "off";
                app.RobertsButton.Visible = "off";
                app.LaplacianofGaussianButton.Visible = "off";

                app.ClusteringButton.Value = 0;
                
                app.ChooseClusteringMethodLabel.Visible = 'off';
                app.KmeansButton.Visible = 'off';
                app.FuzzyCmeansButton.Visible = 'off';
                app.ChooseKmeanKnob.Visible = 'off';
                app.ApplyButton.Visible = 'off';
                app.ChooseKmeanKnobLabel.Visible = 'off';
                app.ChooseCmeanKnob.Visible = "off";
                app.ApplyButton_2.Visible = 'off';
                app.ChooseCmeanKnobLabel.Visible = 'off';
            end

            if isequal(value_TH,1)
                app.ChooseThresholdMethodLabel.Visible = 'on';
                app.MeanButton_2.Visible = 'on';
                app.OtsuButton.Visible = 'on';
                app.AdaptiveThresholdingButton.Visible = 'on';
                app.FixedThresholdingButton.Visible = 'on';
            elseif isequal(value_TH,0)
                app.ChooseThresholdMethodLabel.Visible = 'off';
                app.MeanButton_2.Visible = 'off';
                app.OtsuButton.Visible = 'off';
                app.AdaptiveThresholdingButton.Visible = 'off';
                app.FixedThresholdingButton.Value = 0;
                app.FixedThresholdingButton.Visible = 'off';
                app.ChooseValueForTheThresholdEditFieldLabel.Visible = 'off';
                app.ChooseValueForTheThresholdEditField.Visible = 'off';
            end

            
        end

        % Callback function
        function Clustering(app, event)
            value_CL = app.ClusteringButton.Value;
            
            if isequal(value_CL,1) 
                app.EdgeDetectionButton.Value = 0;
                
                app.ChooseEdgeDetectionMethodLabel.Visible ="off";
                app.CannyButton.Visible = "off";
                app.SobelButton.Visible = "off";
                app.PrewittButton.Visible = "off";
                app.RobertsButton.Visible = "off";
                app.LaplacianofGaussianButton.Visible = "off";

                app.ThresholdingButton.Value = 0;
                
                app.ChooseThresholdMethodLabel.Visible = 'off';
                app.MeanButton_2.Visible = 'off';
                app.OtsuButton.Visible = 'off';
                app.AdaptiveThresholdingButton.Visible = 'off';
                app.FixedThresholdingButton.Value = 0;
                app.FixedThresholdingButton.Visible = 'off';
                app.ChooseValueForTheThresholdEditFieldLabel.Visible = 'off';
                app.ChooseValueForTheThresholdEditField.Visible = 'off';

            end

            if isequal(value_CL,1)
                app.ChooseClusteringMethodLabel.Visible = 'on';
                app.KmeansButton.Visible = 'on';
                app.FuzzyCmeansButton.Visible = 'on';
                
            elseif isequal(value_CL,0)
                app.ChooseClusteringMethodLabel.Visible = 'off';
                app.KmeansButton.Visible = 'off';
                app.FuzzyCmeansButton.Visible = 'off';
                app.SelforganizingMapsButton.Visible = 'off';
                app.ChooseKmeanKnob.Visible = 'off';
                app.ApplyButton.Visible = 'off';
                app.ChooseKmeanKnobLabel.Visible = 'off';
                app.ChooseCmeanKnob.Visible = "off";
                app.ApplyButton_2.Visible = 'off';
                app.ChooseCmeanKnobLabel.Visible = 'off';
            end
        end

        % Callback function
        function FixedThresholding(app, event)
            value_FTH = app.FixedThresholdingButton.Value;
            
            if isequal(value_FTH,1)
                app.ChooseValueForTheThresholdEditFieldLabel.Visible = 'on';
                app.ChooseValueForTheThresholdEditField.Visible = 'on';
            elseif isequal(value_FTH,0)
                app.ChooseValueForTheThresholdEditFieldLabel.Visible = 'off';
                app.ChooseValueForTheThresholdEditField.Visible = 'off';
            end
            
        end

        % Callback function
        function FTH_Function(app, event)
            value = app.ChooseValueForTheThresholdEditField.Value;
            
            gray_image = rgb2gray(app.inputImg2);
            threshold_value = value;

            mask = gray_image < threshold_value;
            mask = imclearborder(mask);
            mask = bwareafilt(mask, 1);
            mask = ~bwareaopen(~mask, 1000);

            app.outputImg2 = mask;
            imshow(app.outputImg2,'parent',app.UIAxes_3);
        end

        % Callback function
        function Mean(app, event)
            gray_image = rgb2gray(app.inputImg2);
            mean_value = mean(gray_image(:));

            mask = gray_image < mean_value;
            mask = imclearborder(mask);
            mask = bwareafilt(mask, 1);
            mask = ~bwareaopen(~mask, 1000);

            app.outputImg2 = mask;
            imshow(app.outputImg2,'parent',app.UIAxes_3);
        end

        % Callback function
        function Otsus(app, event)
            gray_image = rgb2gray(app.inputImg2);
            threshold_value = graythresh(gray_image);
            
            app.outputImg2 = imbinarize(gray_image,threshold_value);

            imshow(app.outputImg2,'parent',app.UIAxes_3);
        end

        % Button down function: UIAxes, UIAxes_12
        function AdaptiveTH(app, event)
            gray_image = rgb2gray(app.inputImg2);
            %threshold_value = adaptthresh(app.inputImg2,0.4,'ForegroundPolarity','dark');
            enhanced_image = adapthisteq(gray_image);
            threshold_value = graythresh(enhanced_image);

            app.outputImg2 = imbinarize(gray_image,threshold_value);

            imshow(app.outputImg2,'parent',app.UIAxes_3);
        end

        % Callback function
        function Canny(app, event)
            gray_image = rgb2gray(app.inputImg2);
            app.outputImg2 = edge(gray_image,"canny");
            imshow(app.outputImg2,'parent',app.UIAxes_3);
        end

        % Callback function
        function Sobel(app, event)
            gray_image = rgb2gray(app.inputImg2);
            app.outputImg2 = edge(gray_image,"sobel");
            imshow(app.outputImg2,'parent',app.UIAxes_3);
        end

        % Callback function
        function Prewitt(app, event)
            gray_image = rgb2gray(app.inputImg2);
            app.outputImg2 = edge(gray_image,"prewitt");
            imshow(app.outputImg2,'parent',app.UIAxes_3);
        end

        % Callback function
        function Roberts(app, event)
            gray_image = rgb2gray(app.inputImg2);
            app.outputImg2 = edge(gray_image,"roberts");
            imshow(app.outputImg2,'parent',app.UIAxes_3);
        end

        % Callback function
        function LOG(app, event)
            gray_image = rgb2gray(app.inputImg2);
            app.outputImg2 = edge(gray_image,"log");
            imshow(app.outputImg2,'parent',app.UIAxes_3);
        end

        % Callback function
        function Kmeans(app, event)
            value_KM = app.KmeansButton.Value;

            if isequal(value_KM,1) 
                app.FuzzyCmeansButton.Value = 0;
                
                app.ChooseCmeanKnob.Visible = "off";
                app.ApplyButton_2.Visible = 'off';
                app.ChooseCmeanKnobLabel.Visible = 'off';
            end

            if isequal(value_KM,1)
                app.ChooseKmeanKnob.Visible = 'on';
                app.ApplyButton.Visible = 'on';
                app.ChooseKmeanKnobLabel.Visible = 'on';
            elseif isequal(value_KM,0)
                app.ChooseKmeanKnob.Visible = 'off';
                app.ApplyButton.Visible = 'off';
                app.ChooseKmeanKnobLabel.Visible = 'off';
            end
        end

        % Callback function
        function FCM(app, event)
            value_FCM = app.FuzzyCmeansButton.Value;
            
            if isequal(value_FCM,1) 
                app.KmeansButton.Value = 0;
                
                app.ChooseKmeanKnob.Visible = 'off';
                app.ApplyButton.Visible = 'off';
                app.ChooseKmeanKnobLabel.Visible = 'off';
            end

            if isequal(value_FCM,1)
                app.ChooseCmeanKnob.Visible = "on";
                app.ApplyButton_2.Visible = 'on';
                app.ChooseCmeanKnobLabel.Visible = 'on';
            elseif isequal(value_FCM,0)
                app.ChooseCmeanKnob.Visible = "off";
                app.ApplyButton_2.Visible = 'off';
                app.ChooseCmeanKnobLabel.Visible = 'off';
            end

        end

        % Callback function
        function KmeansValue(app, event)
            value = app.ChooseKmeanKnob.Value;
            app.KMValue = int32(value);
            display(app.KMValue)
            display(class(app.KMValue))
        end

        % Callback function
        function ApplyKM(app, event)
            gray_image = rgb2gray(app.inputImg2);
            L = imsegkmeans(gray_image,app.KMValue);
            app.outputImg2 = labeloverlay(app.inputImg2,L);
            imshow(app.outputImg2,'parent',app.UIAxes_3);
        end

        % Callback function
        function FCMValue(app, event)
            value = app.ChooseCmeanKnob.Value;
            app.CMValue = int32(value);
            display(app.CMValue)
            display(class(app.CMValue))
        end

        % Callback function
        function ApplyFCM(app, event)
            gray_image = double(rgb2gray(app.inputImg2));
            %gray_image = double(gray_image);
            app.outputImg2 = FCMforImage(gray_image, app.CMValue);
            imshow(app.outputImg2(:,:,app.CMValue),[],'parent',app.UIAxes_3);
        end

        % Button pushed function: Button_3
        function SelectInputStyle3(app, event)
  
                [file, path] = uigetfile('*.*');

                if isequal(file,0)
                    figure(app.IPApplicationUIFigure);
                    return;
                end
                figure(app.IPApplicationUIFigure);
                app.inputImg3 = imread(fullfile(path,file));
                imshow(app.inputImg3,'parent',app.UIAxes_5);

        end

        % Callback function
        function SwitchValue3(app, event)
            value = app.Switch_3.Value;
            
            if strcmp(value,'Browse')
                app.switchValue3 = 1;
            else
                app.switchValue3 = 0;
            end
        end

        % Button pushed function: Button_4
        function ResetInput3(app, event)
            cla(app.UIAxes_5);
            cla(app.UIAxes_6);
            cla(app.UIAxes_7);

            app.inputImg3 = '';
            app.outputImg3 = '';
            clear('cam');
        end

        % Button pushed function: Button_5
        function SaveOutput3(app, event)
            [file, path] = uiputfile({'*.jpg;*.tif;*.png;*.gif','All Image Files';...
            '*.*','All Files' },'Save Image As');
            if isequal(file,0) || isequal(path,0)
                disp('User cancelled the save operation.')
            else
                imwrite(app.middleImg, fullfile(path,file));
            end
        end

        % Callback function
        function SPNoise(app, event)
            gray_image = rgb2gray(app.inputImg3);
            img_gray = im2double(gray_image);
            app.middleImg = imnoise(img_gray, 'salt & pepper');
            imshow(app.middleImg,[],'parent',app.UIAxes_6);
        end

        % Button pushed function: Button_6
        function SaveOutput4(app, event)
            [file, path] = uiputfile({'*.jpg;*.tif;*.png;*.gif','All Image Files';...
            '*.*','All Files' },'Save Image As');
            if isequal(file,0) || isequal(path,0)
                disp('User cancelled the save operation.')
            else
                imwrite(app.outputImg3, fullfile(path,file));
            end
        end

        % Callback function
        function GaussianNoise(app, event)
            gray_image = rgb2gray(app.inputImg3);
            img_gray = im2double(gray_image);
            app.middleImg = imnoise(img_gray, 'gaussian', 0, 0.01);
            imshow(app.middleImg,[],'parent',app.UIAxes_6);
        end

        % Callback function
        function PoissonNoise(app, event)
            gray_image = rgb2gray(app.inputImg3);
            img_gray = im2double(gray_image);
            app.middleImg = imnoise(img_gray, 'poisson');
            imshow(app.middleImg,[],'parent',app.UIAxes_6);
        end

        % Callback function
        function SpeckleNoise(app, event)
            gray_image = rgb2gray(app.inputImg3);
            img_gray = im2double(gray_image);
            app.middleImg = imnoise(img_gray, 'speckle');
            imshow(app.middleImg,[],'parent',app.UIAxes_6);
        end

        % Callback function
        function Median(app, event)

            app.outputImg3 = medfilt2(app.middleImg);
            imshow(app.outputImg3,[],'parent',app.UIAxes_7);
        end

        % Callback function
        function Wiener(app, event)
            app.outputImg3 = wiener2(app.middleImg,[5 5]);
            imshow(app.outputImg3,[],'parent',app.UIAxes_7);
        end

        % Callback function
        function CLSF(app, event)
            img_gray = rgb2gray(app.inputImg3);   % Coloured to Gray Conversion
            img_gray = im2double(img_gray);
            [X, Y] = size(img_gray);
            n_mean = 0;
            n_var = 0.001;
            gamma = 0.01;

            h_xy = fspecial('motion', 45, 45);
            blurred_img = imfilter(img_gray, h_xy, 'conv', 'circular');
            g_xy = imnoise(blurred_img, 'gaussian', n_mean, n_var);

            G_uv = fft2(g_xy);
            G_uv = fftshift(G_uv);
            
            H_uv = fft2(h_xy, X, Y);
            H_uv = fftshift(H_uv);
            
            H_uv_sq_abs = (abs(H_uv)).^2;

            lap_op_spatial=[0 -1 0; -1 4 -1; 0 -1 0];  % Defining a Laplacian Operator
            lap_op = fft2(lap_op_spatial, X, Y); % FFT of Lap Op Equal to the size of image
            lap_op = fftshift(lap_op);
            lap_op_abs_sq = abs(lap_op).^2;
            conj_H_uv = conj(H_uv);

            clsf_restored_img = G_uv.*(conj_H_uv./(H_uv_sq_abs + (gamma * lap_op_abs_sq))) ;

            XY = X*Y; % No of pixel elements
            noise = XY*(n_var + n_mean^2);
            ctr = 0; % to count number of iterations.
            % Iterating for Optimum Value of Gamma
            iteration_stopper = 0;
            accuracy_factor = 0.1;
            while iteration_stopper == 0 % ITERATIONS
                ctr = ctr + 1;
                % R(u,v) = G(u,v) - H(u,v)F_hat(u,v)
                R = abs(G_uv - (H_uv.*clsf_restored_img));
                % r(x,y) = ifft2(R(u,v)
                r = ifft2(R);
                %||r||^2 = Sum_M Sum_N (r(x,y)^2))
                r_sq = r.^2; % Computing the Square value
                r_sq = sum(abs(r_sq(:))); % Summing up the pixel values
                % Conditions Evaluation for Constrainst to be True/Valid
                if abs(r_sq - noise) < accuracy_factor % Condition - 1 (ALL OK)
                    iteration_stopper = 1;  % End loop
                else
                    if r_sq < noise % Condition - 2 (Increase Gamma)
                        gamma = gamma *(1 + accuracy_factor);
                    elseif r_sq > noise % Condition - 3 (Decrease Gamma)
                        gamma = gamma * (1 - accuracy_factor);
                    end
                    clsf_restored_img = G_uv.*(conj_H_uv./(H_uv_sq_abs + (gamma * lap_op_abs_sq)));
                end % End Iterations
            end % End while Loop
            app.outputImg3 = abs(ifft2(clsf_restored_img));
            imshow(app.outputImg3,[],'parent',app.UIAxes_7);
        end

        % Button pushed function: Button_13
        function ResetInput4(app, event)
            cla(app.UIAxes_8);
            cla(app.UIAxes_9);
            cla(app.UIAxes_11);
            cla(app.UIAxes_13);
            app.inputImg4 = '';
            app.outputImg4 = '';
            clear('cam');
        end

        % Button pushed function: Button_2
        function SelectInputStyle4(app, event)
          
            [file, path] = uigetfile('*.*');

            if isequal(file,0)
                figure(app.IPApplicationUIFigure);
                return;
            end
            figure(app.IPApplicationUIFigure);
            app.inputImg4= imread(fullfile(path,file));
            imshow(app.inputImg4,'parent',app.UIAxes_8);

        end

        % Callback function
        function SwitchValue4(app, event)
            value = app.Switch_4.Value;
                        
            if strcmp(value,'Browse')
                app.switchValue4 = 1;
            else
                app.switchValue4 = 0;
            end
        end

        % Button pushed function: Button_14
        function SaveOutput5(app, event)
            [file, path] = uiputfile({'*.jpg;*.tif;*.png;*.gif','All Image Files';...
            '*.*','All Files' },'Save Image As');
            if isequal(file,0) || isequal(path,0)
                disp('User cancelled the save operation.')
            else
                imwrite(app.outputImg4, fullfile(path,file));
            end
        end

        % Button pushed function: Button_7
        function ApplyFilter(app, event)
            
            switch app.ListBox.Value
                case '灰度图像'
                    app.outputImg4 = rgb2gray(app.inputImg4);
                    imshow(app.outputImg4,[],'parent',app.UIAxes_9);
                case '索引图像'
                  % 假设 app.outputImg4 是灰度图像
                    grayImage = rgb2gray(app.inputImg4);
                    
                    % 将灰度图像转换为索引图像，假设使用8位索引
                    numColors = 256; % 8位索引图像的颜色数
                    indexedImage = gray2ind(grayImage, numColors);
                    
                    % 显示索引图像
                    imshow(indexedImage, jet(numColors), 'parent', app.UIAxes_9);


                case 'HSI图像'
                    % 将RGB值标准化到0到1的范围
                    rgb_image = double(app.inputImg4) / 255.0;
                    
                    % 初始化HSI图像
                    hsi_image = zeros(size(rgb_image));
                    
                    % 计算色相（H）
                    R = rgb_image(:, :, 1);
                    G = rgb_image(:, :, 2);
                    B = rgb_image(:, :, 3);
                    
                    numerator = 0.5 * ((R - G) + (R - B));
                    denominator = sqrt((R - G).^2 + (R - B) .* (G - B));
                    theta = acos(numerator ./ (denominator + eps));
                    
                    H = theta;
                    H(B > G) = 2 * pi - H(B > G);
                    H = H / (2 * pi);
                    
                    % 计算饱和度（S）
                    S = 1 - 3 * min(min(R, G), B) ./ (R + G + B + eps);
                    
                    % 计算亮度（I）
                    I = (R + G + B) / 3;
                    
                    % 将HSI通道赋给HSI图像
                    hsi_image(:, :, 1) = H;
                    hsi_image(:, :, 2) = S;
                    hsi_image(:, :, 3) = I;
                    
                    % 显示HSI图像     
                    imshow(hsi_image,[],'parent',app.UIAxes_9);
                case 'RGB分量平滑'
                    I = app.inputImg4;
                    
                    w=ones(5)./25;
                    
                    Ir = imfilter(I(:,:,1),w);
                    Ig = imfilter(I(:,:,2),w);
                    Ib = imfilter(I(:,:,3),w);
                    
                    imshow(cat(3,Ir,Ig,Ib),[],'parent',app.UIAxes_9);
                case 'HSI强度分量平滑'
                    % 将 RGB 图像转换为 HSI
                    rgbImage = double(app.inputImg4) / 255.0;

                    R = rgbImage(:, :, 1);
                    G = rgbImage(:, :, 2);
                    B = rgbImage(:, :, 3);
                
                    % 计算强度（I）
                    I = (R + G + B) / 3;
                
                    % 计算饱和度（S）
                    S = 1 - 3 * min(min(R, G), B) ./ (R + G + B + eps);
                
                    % 计算色调（H）
                    theta = acos((0.5 * ((R - G) + (R - B))) ./ sqrt((R - G).^2 + (R - B) .* (G - B) + eps));
                    H = theta;
                    H(B > G) = 2 * pi - H(B > G);
                    H = H / (2 * pi);
                
                    % 将 HSI 通道合并为 HSI 图像
                    hsiImage = cat(3, H, S, I);
                    
                    % 只处理 HSI 图像的强度分量（I）
                    intensityComponent = hsiImage(:, :, 3);
                    
                    % 使用 5x5 平均模板进行平滑
                    smoothedIntensity = imfilter(intensityComponent, ones(5) / 25);
                    
                    % 将平滑后的强度分量放回 HSI 图像
                    smoothedHSI = hsiImage;
                    smoothedHSI(:, :, 3) = smoothedIntensity;
                    
                    % 将 HSI 图像转换回 RGB
                    H = smoothedHSI(:, :, 1);
    S = smoothedHSI(:, :, 2);
    I = smoothedHSI(:, :, 3);

    % 计算 RGB 的每个通道
    R = zeros(size(H));
    G = zeros(size(H));
    B = zeros(size(H));

    % 根据 H、S、I 的值计算 RGB
    for i = 1:numel(H)
        h = H(i);
        s = S(i);
        i_val = I(i);

        if s == 0
            R(i) = i_val;
            G(i) = i_val;
            B(i) = i_val;
        else
            h = h * 360; % 将 H 转换为 [0, 360] 范围
            if h >= 0 && h < 120
                R(i) = i_val * (1 + s * cosd(h) / cosd(60 - h));
                B(i) = i_val * (1 - s);
                G(i) = 3 * i_val - (R(i) + B(i));
            elseif h >= 120 && h < 240
                h = h - 120;
                G(i) = i_val * (1 + s * cosd(h) / cosd(60 - h));
                R(i) = i_val * (1 - s);
                B(i) = 3 * i_val - (G(i) + R(i));
            else
                h = h - 240;
                B(i) = i_val * (1 + s * cosd(h) / cosd(60 - h));
                G(i) = i_val * (1 - s);
                R(i) = 3 * i_val - (B(i) + G(i));
            end
        end
    end

    % 将 RGB 通道合并为 RGB 图像
    rgbImage = cat(3, R, G, B);

    % 将图像值标准化到 [0, 1] 范围
    rgbImage = max(0, min(1, rgbImage));
                    
                    % 显示处理后的图像
                    imshow(rgbImage, 'parent', app.UIAxes_9);
                    
            end
        end

        % Size changed function: Tab_2
        function Tab_2SizeChanged(app, event)
            position = app.Tab_2.Position;
            
        end

        % Value changing function: gammaSlider
        function gammaSliderValueChanging(app, event)
            changingValue = event.Value;
            if isempty(app.inputImg)
                app.TextArea_3.Value = "请先输入图片";
                return
            end
            app.TextArea_3.Value = "请选择图像增强方法";
            app.tempImg = rgb2gray(app.inputImg);
            FFT = fftshift(fft2(app.tempImg));
            F=real(FFT);
            T=log(F+1);
            imshow(T,[],'parent',app.UIAxes_12);
            imshow(app.outputImg,[],'parent',app.UIAxes_10);
            app.Label_8.Text = "灰度图";
            imshow(app.tempImg,'parent',app.UIAxes_10);
            if strcmp(app.DropDown_2.Value,'灰度变换')
                Low_high = stretchlim(app.tempImg);
                app.outputImg = imadjust(app.tempImg,Low_high,[0 1],changingValue);
                app.TextArea_2.Value = "gamma";
            elseif strcmp(app.DropDown_2.Value,'直方图均衡化')
                app.outputImg=histeq(app.tempImg,round(90*changingValue)+2);
                app.TextArea_2.Value = "灰度级";
            elseif strcmp(app.DropDown_2.Value,'矩形平均滤波')
                w=fspecial('average',[round(100*changingValue)+1 round(100*changingValue)+1]);
                app.outputImg = imfilter(app.tempImg,w,"circular");
                app.TextArea_2.Value = "滤波器尺寸";
            elseif strcmp(app.DropDown_2.Value,'圆形平均滤波')
                w=fspecial('disk',round(100*changingValue)+1);
                app.outputImg = imfilter(app.tempImg,w,"circular");
                app.TextArea_2.Value = "滤波器尺寸";
            elseif strcmp(app.DropDown_2.Value,'高斯滤波')
                w=fspecial('gaussian',[round(100*changingValue)+1 round(100*changingValue)+1],100);
                app.TextArea_2.Value = "滤波器尺寸";
                app.outputImg = imfilter(app.tempImg,w,"circular");
            elseif strcmp(app.DropDown_2.Value,'中值滤波')
                app.outputImg = medfilt2(app.tempImg,[round(100*changingValue)+1 round(100*changingValue)+1],'symmetric');
                app.TextArea_2.Value = "滤波器尺寸";
            elseif strcmp(app.DropDown_2.Value,'理想低通滤波')
                app.TextArea_2.Value = "频率范围";
                F1=fft2(app.tempImg);
                F2=fftshift(F1);
                [M,N]=size(app.tempImg);
                center1=floor(M/2);
                center2=floor(N/2);
                D0=round(100*changingValue)+0.1;
                H=zeros(M,N);
                for i=1:M
                    for j=1:N
                        d=sqrt((i-center1).^2+(j-center2).^2);
                        if d<D0
                            H(i,j)=1;
                        end
                    end
                end
                Fh=F2.*H;
                app.outputImg = abs(ifft2(ifftshift(Fh)));
            elseif strcmp(app.DropDown_2.Value,'Butterworth低通滤波器')
                app.TextArea_2.Value = "频率范围";
                F1=fft2(app.tempImg);
                F2=fftshift(F1);
                [M,N]=size(app.tempImg);
                center1=floor(M/2);
                center2=floor(N/2);
                D0=round(100*changingValue)+0.1;
                H=zeros(M,N);
                n=2;
                for i=1:M
                    for j=1:N
                        d=sqrt((i-center1).^2+(j-center2).^2);
                        H(i,j)=1/(1+(d/D0).^(2*n));
                    end
                end
                Fh=F2.*H;
                app.outputImg = abs(ifft2(ifftshift(Fh)));
             elseif strcmp(app.DropDown_2.Value,'高斯低通滤波器')
                app.TextArea_2.Value = "频率范围";
                F1=fft2(app.tempImg);
                F2=fftshift(F1);
                [M,N]=size(app.tempImg);
                center1=floor(M/2);
                center2=floor(N/2);
                D0=round(100*changingValue)+0.1;
                H=zeros(M,N);
                n=2;
                for i=1:M
                    for j=1:N
                        d=sqrt((i-center1).^2+(j-center2).^2);
                        H(i,j)=exp(-d.^2/(2*D0.^2));
                    end
                end
                Fh=F2.*H;
                app.outputImg = abs(ifft2(ifftshift(Fh)));
            elseif strcmp(app.DropDown_2.Value,'理想高通滤波器')
                app.TextArea_2.Value = "频率范围";
                F1=fft2(app.tempImg);
                F2=fftshift(F1);
                [M,N]=size(app.tempImg);
                center1=floor(M/2);
                center2=floor(N/2);
                D0=round(100*changingValue)+0.1;
                H=zeros(M,N);
                for i=1:M
                    for j=1:N
                        d=sqrt((i-center1).^2+(j-center2).^2);
                        if d>D0
                            H(i,j)=1;
                        end
                    end
                end
                Fh=F2.*H;
                app.outputImg = abs(ifft2(ifftshift(Fh)));
            elseif strcmp(app.DropDown_2.Value,'Butterworth高通滤波器')
                app.TextArea_2.Value = "频率范围";
                F1=fft2(app.tempImg);
                F2=fftshift(F1);
                [M,N]=size(app.tempImg);
                center1=floor(M/2);
                center2=floor(N/2);
                D0=round(100*changingValue)+0.1;
                H=zeros(M,N);
                n=2;
                for i=1:M
                    for j=1:N
                        d=sqrt((i-center1).^2+(j-center2).^2);
                        H(i,j)=1-1/(1+(d/D0).^2*n);
                    end
                end
                Fh=F2.*H;
                app.outputImg = abs(ifft2(ifftshift(Fh)));
             elseif strcmp(app.DropDown_2.Value,'高斯高通滤波器')
                app.TextArea_2.Value = "频率范围";
                F1=fft2(app.tempImg);
                F2=fftshift(F1);
                [M,N]=size(app.tempImg);
                center1=floor(M/2);
                center2=floor(N/2);
                D0=round(100*changingValue)+0.1;
                H=zeros(M,N);
                for i=1:M
                    for j=1:N
                        d=sqrt((i-center1).^2+(j-center2).^2);
                        H(i,j)=1-exp(-d.^2/(2*(D0).^2));
                    end
                end
                Fh=F2.*H;
                app.outputImg = abs(ifft2(ifftshift(Fh)));
            end
            imshow(app.outputImg,[],'parent',app.UIAxes_2);
        end

        % Button down function: Tab_2
        function Tab_2ButtonDown(app, event)
            
        end

        % Value changing function: Slider_3
        function Slider_3ValueChanging(app, event)
            changingValue = event.Value;
            if isempty(app.inputImg3)
                app.TextArea_4.Value = "请先输入图片!";
                return
            end
            app.TextArea_4.Value = "请选择图像污染方法!";
            app.tempImg = rgb2gray(app.inputImg3);
            if strcmp(app.ListBox_2.Value,'椒盐噪声')
                img_gray = im2double(app.tempImg);
                app.middleImg = imnoise(img_gray, 'salt & pepper',changingValue);
                app.pollute=1;
            elseif strcmp(app.ListBox_2.Value,'高斯噪声')
                img_gray = im2double(app.tempImg);
                app.middleImg = imnoise(img_gray, 'gaussian', 0, changingValue);
                app.pollute=1;
            elseif strcmp(app.ListBox_2.Value,'盐噪声')
                img_gray = im2double(app.tempImg);
                app.middleImg = imnoise(img_gray, 'salt', changingValue);
                app.pollute=1;
            elseif strcmp(app.ListBox_2.Value,'泊松噪声')
                img_gray = im2double(app.tempImg);
                app.middleImg = imnoise(img_gray, 'poisson');
                app.pollute=1;
            elseif strcmp(app.ListBox_2.Value,'斑点噪声')
                img_gray = im2double(app.tempImg);
                app.middleImg = imnoise(img_gray, 'speckle',changingValue);
                app.pollute=1;
            elseif strcmp(app.ListBox_2.Value,'运动模糊')
                img_gray = im2double(app.tempImg);
                app.motion = fspecial('motion', round(100*changingValue)+1,0);
                app.middleImg = imfilter(img_gray, app.motion, 'conv', 'circular');
                app.pollute=2;
            elseif strcmp(app.ListBox_2.Value,'大气湍流模型')
                img_gray = im2double(app.tempImg);
                %% 剧烈大气湍流退化模型
                F=fft2(img_gray);%换到频域，信号在低频，噪声在高频
                F=fftshift(F);%频谱移到矩阵中心
                %执行
                [W,H]=size(F);
                [u,v]=meshgrid(1:W,1:H);%生成矩阵
                app.motion=exp(-changingValue*0.01* ( (u-W/2).^2+(v-H/2).^2).^(5/6) );
                F=F.*app.motion;
                %傅里叶反变换
                X=ifftshift(F);
                turimg=ifft2(X);
                app.middleImg=uint8(abs(turimg)*256);
                app.pollute=2;
             elseif strcmp(app.ListBox_2.Value,'运动模糊加性高斯噪声')
                img_gray = im2double(app.tempImg);
                app.motion = fspecial('motion', round(100*changingValue)+1,0);
                app.middleImg = imfilter(img_gray, app.motion, 'conv', 'circular');
                app.middleImg = imnoise(app.middleImg, 'gaussian', 0, 0.003);
                app.pollute=2;
            end
            imshow(app.middleImg,[],'parent',app.UIAxes_6);
        end

        % Value changing function: Slider_4
        function Slider_4ValueChanging(app, event)
            changingValue = event.Value;
            if isempty(app.inputImg3)
                app.TextArea_4.Value = "请先输入图片并污染图片";
                return
            end

            if app.pollute==0
                app.TextArea_4.Value = "请先污染图片";
                return
            end
            app.TextArea_4.Value = "请选择图像恢复方法!";
            if strcmp(app.ListBox_3.Value,'中值滤波器')
                
                 app.outputImg3 = medfilt2(app.middleImg,[round(100*changingValue)+1 round(100*changingValue)+1],'symmetric');
            elseif strcmp(app.ListBox_3.Value,'均值滤波器')
                % 定义均值滤波器
                filterSize = [round(100*changingValue)+1, round(100*changingValue)+1];  % 滤波器大小，可以根据需要调整
                filter = ones(filterSize) / prod(filterSize);  % 构建均值滤波器
                % 对图像应用均值滤波器
                app.outputImg3 = imfilter(app.middleImg, filter,'replicate');
            elseif strcmp(app.ListBox_3.Value,'维纳逆滤波')
                if app.pollute~=2
                    app.TextArea_4.Value = "维纳逆滤波仅能作用在运动模糊和大气湍流模型";
                    return
                end
                  app.middleImg = double(app.middleImg) / 256;
                 app.outputImg3 = deconvwnr(app.middleImg, app.motion, changingValue*0.5);
            elseif strcmp(app.ListBox_3.Value,'维纳滤波器')
                 app.outputImg3 = wiener2(app.middleImg,[5 5],changingValue*50);
            end
            imshow(app.outputImg3,[],'parent',app.UIAxes_7);
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
                grayImage = rgb2gray(app.inputImg4);
                imshow(grayImage,[],'parent',app.UIAxes_11);
                switch app.ListBox_4.Value
                    case '强度分层'
                        % 将 RGB 图像转换为灰度图像
                        grayImage = rgb2gray(app.inputImg4);

                        % 使用 grayslice 进行强度分层
                        numLevels = 8; % 设置强度级别的数量
                        outputImage = grayslice(grayImage, numLevels);

                        % 显示分层后的图像
                        imshow(outputImage,jet(8), 'parent', app.UIAxes_13);

                    case '传递函数'                      
grayImage = rgb2gray(app.inputImg4);

% 获取图像的亮度范围
L = double(max(grayImage(:))); % 254 (<255)

% 初始化RGB图像
colorImage = zeros(size(grayImage, 1), size(grayImage, 2), 3); % 398*500*3

% (1) 红色分量的传递函数
redChannel = zeros(size(grayImage)); % 398*500
redChannel(grayImage < L/2) = 0; % 小于L/2的像素为暗红色
redChannel(grayImage >= L/2 & grayImage <= 3*L/4) = (4*grayImage(grayImage >= L/2 & grayImage <= 3*L/4) - 2*L) / L; % 从暗到亮的线性变换
redChannel(grayImage > 3*L/4) = L; % 大于3L/4的像素变成最亮的红色

% (2) 绿色分量的传递函数
greenChannel = zeros(size(grayImage));
greenChannel(grayImage < L/4) = (4*grayImage(grayImage < L/4)) / L; % 从暗到亮的线性变换
greenChannel(grayImage >= L/4 & grayImage <= 3*L/4) = 1; % L/4到3L/4之间的像素变成最亮的绿色
greenChannel(grayImage > 3*L/4) = (4*grayImage(grayImage > 3*L/4) - 3*L) / L; % 从亮到暗的线性变换

% (3) 蓝色分量的传递函数
blueChannel = zeros(size(grayImage));
blueChannel(grayImage < L/4) = L; % 小于L/4的像素变成最亮的蓝色
blueChannel(grayImage >= L/4 & grayImage <= L/2) = (4*grayImage(grayImage >= L/4 & grayImage <= L/2) - L) / L; % 从亮到暗的线性变换
blueChannel(grayImage > L/2) = 0; % 大于L/2的像素为暗蓝色

% 合并分量得到彩色图像
colorImage(:,:,1) = redChannel;
colorImage(:,:,2) = greenChannel;
colorImage(:,:,3) = blueChannel;

imshow(colorImage,[], 'parent', app.UIAxes_13);

                end 
        end

        % Value changed function: ListBox_4
        function ListBox_4ValueChanged(app, event)
            value = app.ListBox_4.Value;
            
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            
            [file, path] = uigetfile('*.*');

            if isequal(file,0)
                figure(app.IPApplicationUIFigure);
                return;
            end
            figure(app.IPApplicationUIFigure);
            app.inputImg5= imread(fullfile(path,file));
            imshow(app.inputImg5,'parent',app.UIAxes_14);
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            a=1
            switch app.ListBox_5.Value
                case '霍夫曼编码'
                    originalImage = app.inputImg5; % 替换成你的图像路径
                    if a<0
                    % 转换图像为灰度
                    grayImage = rgb2gray(originalImage);
                    
                    % 计算灰度直方图
                    histogram = imhist(grayImage);
                    
                    % 构建霍夫曼树和字典
                    huffmanDict = huffmandict(0:255, histogram);
                    
                    % 生成霍夫曼编码
                    huffmanCodes = huffmanenco(grayImage(:), huffmanDict);
                    
                    % 将编码解码为图像
                    decodedImage = huffmandeco(huffmanCodes, huffmanDict);
                    
                    % 将解码后的向量转换回图像矩阵
                    decodedImageMatrix = reshape(decodedImage, size(grayImage));
                    else
                       imshow(app.inputImg5,'parent',app.UIAxes_15); 
                    end
                case '预测编码'   % 多通道有问题啊
                    
                    original_image = app.inputImg5;
                            grayscale_image = rgb2gray(original_image);                                            
                            % 差分预测和编码
                            predicted_image = zeros(size(grayscale_image));
                            encoded_diff = zeros(size(grayscale_image));
                            
                            for i = 2:size(grayscale_image, 1)
                                for j = 2:size(grayscale_image, 2)
                                    predicted_image(i, j) = grayscale_image(i - 1, j);
                                    diff = grayscale_image(i, j) - predicted_image(i, j);
                                    encoded_diff(i, j) = diff;
                                end
                            end
                                                 
                            imshow(predicted_image,[],'parent',app.UIAxes_15); 
                case '奇异值分解'
                    % 读取图像
                    originalImage = app.inputImg5
                    
                    % 分离RGB通道
                    redChannel = originalImage(:, :, 1);
                    greenChannel = originalImage(:, :, 2);
                    blueChannel = originalImage(:, :, 3);
                    
                    % 奇异值分解每个通道
                    [U_r, S_r, V_r] = svd(double(redChannel));
                    [U_g, S_g, V_g] = svd(double(greenChannel));
                    [U_b, S_b, V_b] = svd(double(blueChannel));
                    
                    % 选择保留的奇异值数量，这里选择前k个奇异值
                    k = app.EditField.Value; % 可以根据需要调整
                    
                    % 对每个通道进行压缩
                    Ur = U_r(:, 1:k);
                    Sr = S_r(1:k, 1:k);
                    Vr = V_r(:, 1:k);
                    compressedRedChannel = Ur * Sr * Vr';
                    
                    Ug = U_g(:, 1:k);
                    Sg = S_g(1:k, 1:k);
                    Vg = V_g(:, 1:k);
                    compressedGreenChannel = Ug * Sg * Vg';
                    
                    Ub = U_b(:, 1:k);
                    Sb = S_b(1:k, 1:k);
                    Vb = V_b(:, 1:k);
                    compressedBlueChannel = Ub * Sb * Vb';
                    
                    % 合并压缩后的通道
                    compressedImage = cat(3, uint8(compressedRedChannel), uint8(compressedGreenChannel), uint8(compressedBlueChannel));
                    
                    
                     imshow(compressedImage,[],'parent',app.UIAxes_15); 


            end
        end

        % Value changed function: TextArea_3
        function TextArea_3ValueChanged(app, event)
            value = app.TextArea_3.Value;
            if isnan(app.inputImg)
                app.TextArea_3.Value = "请先输入图片！";
            end
        end

        % Button pushed function: Button_16
        function Button_16Pushed(app, event)
            switch app.ListBox_6.Value
                case 'Sharpen Filter'
                    sharpen = [0,-1,0;-1,5,-1;0,-1,0];
                    app.outputImg4 = imfilter(app.inputImg4, sharpen);
                    imshow(app.outputImg4,[],'parent',app.UIAxes_16);
                case 'Emboss Filter'
                    emboss = [-2,-1,0;-1,1,1;0,1,2];
                    app.outputImg4 = imfilter(app.inputImg4, emboss);
                    imshow(app.outputImg4,[],'parent',app.UIAxes_16);
                case 'Sobel Filter'
                    sob_gray = rgb2gray(app.inputImg4);
                    app.outputImg4 = edge(sob_gray,'sobel');
                    imshow(app.outputImg4,[],'parent',app.UIAxes_16);
                case 'Prewitt Filter'
                    pre_gray = rgb2gray(app.inputImg4);
                    app.outputImg4 = edge(pre_gray,'prewitt');
                    imshow(app.outputImg4,[],'parent',app.UIAxes_16);
                case 'Roberts Filter'
                    rob_gray = rgb2gray(app.inputImg4);
                    app.outputImg4 = edge(rob_gray,'roberts');
                    imshow(app.outputImg4,[],'parent',app.UIAxes_16);
                case 'LOG Filter'
                    log_gray = rgb2gray(app.inputImg4);
                    app.outputImg4 = edge(log_gray,'log');
                    imshow(app.outputImg4,[],'parent',app.UIAxes_16);
                case 'Cartoon Filter'
                    cart_gray = rgb2gray(app.inputImg4);
                    median_image = medfilt2(cart_gray);
                    edge_image = edge(median_image, 'canny');
                    se = strel('square', 3);
                    dilated_image = imdilate(edge_image, se);
                    color_image = imcomplement(dilated_image);
                    color_image = im2double(color_image);
                    double_cartoon = im2double(app.inputImg4);
                    color_image = repmat(color_image, [1,1,3]);
                    app.outputImg4 = imlincomb(0.5, double_cartoon, 1, color_image);
                    imshow(app.outputImg4,[],'parent',app.UIAxes_16);
                case 'Vignette Filter'
                    vig_gray = rgb2gray(app.inputImg4);
                    double_vig = im2double(app.inputImg4);
                    [rows, cols] = size(vig_gray);
                    center_x = ceil(cols/2);
                    center_y = ceil(rows/2);
                    [X,Y] = meshgrid(1:cols, 1:rows);
                    kernel = ones(rows, cols);
                    kernel(:) = sqrt((X - center_x).^2 + (Y - center_y).^2);
                    kernel = 1./(1 + kernel/mean(kernel(:)));
                    kernel = (kernel - min(kernel(:))) / (max(kernel(:)) - min(kernel(:)));
                    app.outputImg4 = imlincomb(1, double_vig, 0.7, double_vig .* repmat(kernel, [1,1,3]));
                    imshow(app.outputImg4,[],'parent',app.UIAxes_16);
                case 'Sepia Filter'
                    double_sep = im2double(app.inputImg4);
                    M = [0.393 0.769 0.189; 0.349 0.686 0.168; 0.272 0.534 0.131];
                    app.outputImg4 = im2double(reshape((M*reshape(permute(double_sep, [3 1 2]), 3, [])).', [size(double_sep,1) size(double_sep,2), 3]));

                    imshow(app.outputImg4,[],'parent',app.UIAxes_16);
                case 'Negative Filter'
                    app.outputImg4 = imcomplement(app.inputImg4);
                    imshow(app.outputImg4,[],'parent',app.UIAxes_16);
                case 'Solarize Filter'
                    double_sol = im2double(app.inputImg4);
                    filtered_image = double_sol;
                    filtered_image(double_sol > 0.5) = 1 - double_sol(double_sol > 0.5);
                    app.outputImg4 = filtered_image;
                    imshow(app.outputImg4,[],'parent',app.UIAxes_16);
                case 'Posterize Filter'
                    double_post = im2double(app.inputImg4);
                    filtered_image = double_post;

                    filtered_image(double_post > 0.75) = 1;
                    filtered_image(double_post > 0.5 & double_post <= 0.75) = 0.75;
                    filtered_image(double_post > 0.25 & double_post <= 0.5) = 0.5;
                    filtered_image(double_post <= 0.25) = 0.25;

                    app.outputImg4 = filtered_image;
                    imshow(app.outputImg4,[],'parent',app.UIAxes_16);
            end
        end

        % Button pushed function: Button_15
        function Button_15Pushed(app, event)
              
                [file, path] = uigetfile('*.*');

                if isequal(file,0)
                    figure(app.IPApplicationUIFigure);
                    return;
                end
                figure(app.IPApplicationUIFigure);
                app.inputImg4 = imread(fullfile(path,file));
                imshow(app.inputImg4,'parent',app.UIAxes_17);
        end

        % Value changed function: ListBox_6
        function ListBox_6ValueChanged(app, event)
            value = app.ListBox_6.Value;
            
        end

        % Button pushed function: Button_20
        function Button_20Pushed(app, event)
            switch app.ListBox_7.Value
                case 'Sobel算子'
                    % 读取图像
                    originalImage = app.inputImg4; % 请替换成你的图像文件路径
                    
                    % 将图像转换为灰度图
                    grayImage = rgb2gray(originalImage);
                    
                    % 使用Sobel算子进行边缘检测
                    sobelHorizontal = [-1, -2, -1; 0, 0, 0; 1, 2, 1];
                    sobelVertical = [-1, 0, 1; -2, 0, 2; -1, 0, 1];
                    
                    % 对图像进行水平方向和垂直方向的Sobel滤波
                    filteredImageHorizontal = conv2(double(grayImage), sobelHorizontal, 'same');
                    filteredImageVertical = conv2(double(grayImage), sobelVertical, 'same');
                    
                    % 计算梯度幅值
                    gradientMagnitude = sqrt(filteredImageHorizontal.^2 + filteredImageVertical.^2);
                    
                    imshow(uint8(gradientMagnitude),[],'parent',app.UIAxes_18);
                case 'canny算子'
                    % 读取图像
                        originalImage = app.inputImg4; % 请替换成你的图像文件路径
                        
                        % 将图像转换为灰度图
                        grayImage = rgb2gray(originalImage);
                        
                        % 使用Canny算子进行边缘检测
                        cannyEdges = edge(grayImage, 'canny');
                        
                        % 显示Canny边缘检测结果
                        imshow(cannyEdges, 'parent', app.UIAxes_18);
                case '基于区域的分割'
                        % 读取图像
                        originalImage = app.inputImg4; % 请替换成你的图像文件路径
                        
                        % 将图像转换为灰度图
                        grayImage = rgb2gray(originalImage);
                        
                        % 使用Sobel算子进行边缘检测
                        sobelHorizontal = [-1, -2, -1; 0, 0, 0; 1, 2, 1];
                        sobelVertical = [-1, 0, 1; -2, 0, 2; -1, 0, 1];
                        
                        % 对图像进行水平方向和垂直方向的Sobel滤波
                        filteredImageHorizontal = conv2(double(grayImage), sobelHorizontal, 'same');
                        filteredImageVertical = conv2(double(grayImage), sobelVertical, 'same');
                        
                        % 计算梯度幅值
                        gradientMagnitude = sqrt(filteredImageHorizontal.^2 + filteredImageVertical.^2);
                        
                        % 选择适当的阈值进行分割
                   
                        threshold= app.EditField_2.Value; % 可以根据需要调整
                        binaryImage = gradientMagnitude > threshold;
                        
                        % 使用连通区域分析获取不同区域的标签
                        connectedComponents = bwlabel(binaryImage);
                        
                        % 在原始图像上显示分割结果
                        segmentedImg = label2rgb(connectedComponents);
                        imshow(segmentedImg, 'parent', app.UIAxes_18);


                  end
        end

        % Button pushed function: Button_19
        function Button_19Pushed(app, event)
            
                [file, path] = uigetfile('*.*');

                if isequal(file,0)
                    figure(app.IPApplicationUIFigure);
                    return;
                end
                figure(app.IPApplicationUIFigure);
                app.inputImg4 = imread(fullfile(path,file));
                imshow(app.inputImg4,'parent',app.UIAxes_19);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.IPApplicationUIFigure = uifigure('Visible', 'off');
            app.IPApplicationUIFigure.Name = 'IP Application';
            app.TabGroup = uitabgroup(app.IPApplicationUIFigure);
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.SizeChangedFcn = createCallbackFcn(app, @Tab_2SizeChanged, true);
            app.Tab_2.Title = '图像增强';
            app.Tab_2.ButtonDownFcn = createCallbackFcn(app, @Tab_2ButtonDown, true);
            app.UIAxes_2 = uiaxes(app.Tab_2);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.Visible = 'off';
            app.UIAxes = uiaxes(app.Tab_2);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Visible = 'off';
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @AdaptiveTH, true);
            app.UIAxes_10 = uiaxes(app.Tab_2);
            app.UIAxes_10.XTick = [];
            app.UIAxes_10.YTick = [];
            app.UIAxes_10.Visible = 'off';
            app.UIAxes_12 = uiaxes(app.Tab_2);
            app.UIAxes_12.XTick = [];
            app.UIAxes_12.YTick = [];
            app.UIAxes_12.Visible = 'off';
            app.UIAxes_12.ButtonDownFcn = createCallbackFcn(app, @AdaptiveTH, true);
            app.Label_4 = uilabel(app.Tab_2);
            app.Label_4.Text = '图像增强方式';
            app.Button = uibutton(app.Tab_2, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @SelectInputStyle, true);
            app.Button.Text = '点击选择图片';
            app.Button_11 = uibutton(app.Tab_2, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @ResetInput, true);
            app.Button_11.Text = '重置';
            app.Button_12 = uibutton(app.Tab_2, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @SaveOutput, true);
            app.Button_12.Text = '保存';
            app.Slider_2 = uislider(app.Tab_2);
            app.Slider_2.Limits = [0.3 1];
            app.Slider_2.ValueChangingFcn = createCallbackFcn(app, @Contrast, true);
            app.Slider_2.Value = 0.3;
            app.Label = uilabel(app.Tab_2);
            app.Label.Text = '显示选择的图片';
            app.Label_2 = uilabel(app.Tab_2);
            app.Label_2.Text = '处理后的图片';
            app.Label_27 = uilabel(app.Tab_2);
            app.Label_27.Text = '重置\保存图片';
            app.Label_3 = uilabel(app.Tab_2);
            app.Label_3.Text = '彩色图像像素增强';
            app.DropDown = uidropdown(app.Tab_2);
            app.DropDown.Items = {'色调', '饱和度', '亮度', '对比度'};
            app.DropDown.Value = '色调';
            app.Label_5 = uilabel(app.Tab_2);
            app.Label_5.Text = '灰度图像增强';
            app.DropDown_2 = uidropdown(app.Tab_2);
            app.DropDown_2.Items = {'灰度变换', '直方图均衡化', '矩形平均滤波', '圆形平均滤波', '高斯滤波', '中值滤波', '理想低通滤波', 'Butterworth低通滤波器', '高斯低通滤波器', '理想高通滤波器', 'Butterworth高通滤波器', '高斯高通滤波器'};
            app.DropDown_2.Value = '灰度变换';
            app.gammaSlider = uislider(app.Tab_2);
            app.gammaSlider.Limits = [0 1];
            app.gammaSlider.ValueChangingFcn = createCallbackFcn(app, @gammaSliderValueChanging, true);
            app.Label_6 = uilabel(app.Tab_2);
            app.Label_6.Text = '参数';
            app.TextArea = uitextarea(app.Tab_2);
            app.Label_7 = uilabel(app.Tab_2);
            app.Label_7.Text = '参数';
            app.TextArea_2 = uitextarea(app.Tab_2);
            app.Label_8 = uilabel(app.Tab_2);
            app.Label_8.Text = '中间过程图';
            app.Label_18 = uilabel(app.Tab_2);
            app.Label_18.Text = '二维傅里叶变换';
            app.Label_26 = uilabel(app.Tab_2);
            app.Label_26.Text = '当前状态';
            app.TextArea_3 = uitextarea(app.Tab_2);
            app.TextArea_3.ValueChangedFcn = createCallbackFcn(app, @TextArea_3ValueChanged, true);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '图像复原\重建';
            app.UIAxes_5 = uiaxes(app.Tab);
            app.UIAxes_5.XTick = [];
            app.UIAxes_5.YTick = [];
            app.UIAxes_5.Visible = 'off';
            app.UIAxes_6 = uiaxes(app.Tab);
            app.UIAxes_6.XTick = [];
            app.UIAxes_6.YTick = [];
            app.UIAxes_6.Visible = 'off';
            app.UIAxes_7 = uiaxes(app.Tab);
            app.UIAxes_7.XTick = [];
            app.UIAxes_7.YTick = [];
            app.UIAxes_7.Visible = 'off';
            app.Button_3 = uibutton(app.Tab, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @SelectInputStyle3, true);
            app.Button_3.Text = '选择图片';
            app.Label_12 = uilabel(app.Tab);
            app.Label_12.Text = '1、选择图像污染模型';
            app.Label_16 = uilabel(app.Tab);
            app.Label_16.Text = '2. 选择去噪\重构方式';
            app.Button_4 = uibutton(app.Tab, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @ResetInput3, true);
            app.Button_4.Text = '重置';
            app.Button_5 = uibutton(app.Tab, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @SaveOutput3, true);
            app.Button_5.Text = '保存噪声图片';
            app.Label_17 = uilabel(app.Tab);
            app.Label_17.Text = '3、重置\保存';
            app.Button_6 = uibutton(app.Tab, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @SaveOutput4, true);
            app.Button_6.Text = '保存结果图片';
            app.Label_13 = uilabel(app.Tab);
            app.Label_13.Text = '导入图片';
            app.Label_14 = uilabel(app.Tab);
            app.Label_14.Text = '污染图片';
            app.Label_15 = uilabel(app.Tab);
            app.Label_15.Text = '结果图片';
            app.Slider_3 = uislider(app.Tab);
            app.Slider_3.Limits = [0 1];
            app.Slider_3.ValueChangingFcn = createCallbackFcn(app, @Slider_3ValueChanging, true);
            app.ListBox_2 = uilistbox(app.Tab);
            app.ListBox_2.Items = {'盐噪声', '椒盐噪声', '高斯噪声', '斑点噪声', '泊松噪声', '运动模糊', '大气湍流模型', '运动模糊加性高斯噪声'};
            app.ListBox_2.Value = '椒盐噪声';
            app.ListBox_3 = uilistbox(app.Tab);
            app.ListBox_3.Items = {'中值滤波器', '均值滤波器', '维纳滤波器', '维纳逆滤波'};
            app.ListBox_3.Value = '中值滤波器';
            app.Slider_4 = uislider(app.Tab);
            app.Slider_4.Limits = [0 1];
            app.Slider_4.ValueChangingFcn = createCallbackFcn(app, @Slider_4ValueChanging, true);
            app.TextArea_4Label = uilabel(app.Tab);
            app.TextArea_4Label.Text = '当前状态';
            app.TextArea_4 = uitextarea(app.Tab);
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '彩色图像处理';
            app.UIAxes_8 = uiaxes(app.Tab_3);
            app.UIAxes_8.XTick = [];
            app.UIAxes_8.YTick = [];
            app.UIAxes_8.Visible = 'off';
            app.UIAxes_9 = uiaxes(app.Tab_3);
            app.UIAxes_9.XTick = [];
            app.UIAxes_9.YTick = [];
            app.UIAxes_9.Visible = 'off';
            app.UIAxes_11 = uiaxes(app.Tab_3);
            app.UIAxes_11.XTick = [];
            app.UIAxes_11.YTick = [];
            app.UIAxes_11.Visible = 'off';
            app.UIAxes_13 = uiaxes(app.Tab_3);
            app.UIAxes_13.XTick = [];
            app.UIAxes_13.YTick = [];
            app.UIAxes_13.Visible = 'off';
            app.Label_9 = uilabel(app.Tab_3);
            app.Label_9.Text = '原图片';
            app.Label_10 = uilabel(app.Tab_3);
            app.Label_10.Text = '处理后的图片';
            app.Button_2 = uibutton(app.Tab_3, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @SelectInputStyle4, true);
            app.Button_2.Text = '选择图片';
            app.Button_13 = uibutton(app.Tab_3, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @ResetInput4, true);
            app.Button_13.Text = '重置';
            app.Button_14 = uibutton(app.Tab_3, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @SaveOutput5, true);
            app.Button_14.Text = '保存';
            app.Label_28 = uilabel(app.Tab_3);
            app.Label_28.Text = '重置\保存图片';
            app.Label_19 = uilabel(app.Tab_3);
            app.Label_19.Text = '彩色图像转到';
            app.ListBox = uilistbox(app.Tab_3);
            app.ListBox.Items = {'灰度图像', '索引图像', 'HSI图像', 'RGB分量平滑', 'HSI强度分量平滑'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = 'HSI图像';
            app.Button_7 = uibutton(app.Tab_3, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @ApplyFilter, true);
            app.Button_7.Text = '应用';
            app.Label_11 = uilabel(app.Tab_3);
            app.Label_11.Text = '原图片灰度图';
            app.Label_20 = uilabel(app.Tab_3);
            app.Label_20.Text = '灰度到彩色的转换';
            app.ListBox_4 = uilistbox(app.Tab_3);
            app.ListBox_4.Items = {'强度分层', '传递函数'};
            app.ListBox_4.ValueChangedFcn = createCallbackFcn(app, @ListBox_4ValueChanged, true);
            app.ListBox_4.Value = '传递函数';
            app.Button_8 = uibutton(app.Tab_3, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '应用';
            app.Label_21 = uilabel(app.Tab_3);
            app.Label_21.Text = '处理后的图片';
            app.Tab_4 = uitab(app.TabGroup);
            app.Tab_4.Title = '图像压缩';
            app.UIAxes_14 = uiaxes(app.Tab_4);
            app.UIAxes_14.XTick = [];
            app.UIAxes_14.YTick = [];
            app.UIAxes_14.Visible = 'off';
            app.UIAxes_15 = uiaxes(app.Tab_4);
            app.UIAxes_15.XTick = [];
            app.UIAxes_15.YTick = [];
            app.UIAxes_15.Visible = 'off';
            app.Button_9 = uibutton(app.Tab_4, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '选择图片';
            app.Label_22 = uilabel(app.Tab_4);
            app.Label_22.Text = '原图片';
            app.Label_23 = uilabel(app.Tab_4);
            app.Label_23.Text = '解码后图片';
            app.ListBox_5 = uilistbox(app.Tab_4);
            app.ListBox_5.Items = {'霍夫曼编码', '预测编码', '奇异值分解'};
            app.ListBox_5.Value = '预测编码';
            app.Button_10 = uibutton(app.Tab_4, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '应用';
            app.Label_24 = uilabel(app.Tab_4);
            app.Label_24.Text = '奇异值数量';
            app.EditField = uieditfield(app.Tab_4, 'numeric');
            app.Tab_5 = uitab(app.TabGroup);
            app.Tab_5.Title = '图像滤波';
            app.UIAxes_16 = uiaxes(app.Tab_5);
            app.UIAxes_16.XTick = [];
            app.UIAxes_16.YTick = [];
            app.UIAxes_16.Visible = 'off';
            app.UIAxes_17 = uiaxes(app.Tab_5);
            app.UIAxes_17.XTick = [];
            app.UIAxes_17.YTick = [];
            app.UIAxes_17.Visible = 'off';
            app.ListBox_6 = uilistbox(app.Tab_5);
            app.ListBox_6.Items = {'Negative Filter', 'Sharpen Filter', 'Emboss Filter', 'Sobel Filter', 'Prewitt Filter', 'Roberts Filter', 'LOG Filter', 'Cartoon Filter', 'Vignette Filter', 'Sepia Filter', 'Solarize Filter', 'Posterize Filter'};
            app.ListBox_6.ValueChangedFcn = createCallbackFcn(app, @ListBox_6ValueChanged, true);
            app.ListBox_6.Value = 'Negative Filter';
            app.Button_15 = uibutton(app.Tab_5, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @Button_15Pushed, true);
            app.Button_15.Text = '选择图片';
            app.Button_16 = uibutton(app.Tab_5, 'push');
            app.Button_16.ButtonPushedFcn = createCallbackFcn(app, @Button_16Pushed, true);
            app.Button_16.Text = '应用';
            app.Label_29 = uilabel(app.Tab_5);
            app.Label_29.Text = '彩色图像转到';
            app.Label_30 = uilabel(app.Tab_5);
            app.Label_30.Text = '重置\保存图片';
            app.Button_17 = uibutton(app.Tab_5, 'push');
            app.Button_17.Text = '重置';
            app.Button_18 = uibutton(app.Tab_5, 'push');
            app.Button_18.Text = '保存';
            app.Label_31 = uilabel(app.Tab_5);
            app.Label_31.Text = '处理后的图片';
            app.Label_32 = uilabel(app.Tab_5);
            app.Label_32.Text = '原图片';
            app.Tab_6 = uitab(app.TabGroup);
            app.Tab_6.Title = '图像分割';
            app.UIAxes_18 = uiaxes(app.Tab_6);
            app.UIAxes_18.XTick = [];
            app.UIAxes_18.YTick = [];
            app.UIAxes_18.Visible = 'off';
            app.UIAxes_19 = uiaxes(app.Tab_6);
            app.UIAxes_19.XTick = [];
            app.UIAxes_19.YTick = [];
            app.UIAxes_19.Visible = 'off';
            app.ListBox_7 = uilistbox(app.Tab_6);
            app.ListBox_7.Items = {'Sobel算子', 'canny算子', '基于区域的分割'};
            app.ListBox_7.Value = 'Sobel算子';
            app.Button_19 = uibutton(app.Tab_6, 'push');
            app.Button_19.ButtonPushedFcn = createCallbackFcn(app, @Button_19Pushed, true);
            app.Button_19.Text = '选择图片';
            app.Button_20 = uibutton(app.Tab_6, 'push');
            app.Button_20.ButtonPushedFcn = createCallbackFcn(app, @Button_20Pushed, true);
            app.Button_20.Text = '应用';
            app.Label_33 = uilabel(app.Tab_6);
            app.Label_33.Text = '图像分割';
            app.Label_35 = uilabel(app.Tab_6);
            app.Label_35.Text = '处理后的图片';
            app.Label_36 = uilabel(app.Tab_6);
            app.Label_36.Text = '原图片';
            app.Label_37 = uilabel(app.Tab_6);
            app.Label_37.Text = '分割阈值';
            app.EditField_2 = uieditfield(app.Tab_6, 'numeric');
            app.APPLabel = uilabel(app.IPApplicationUIFigure);
            app.APPLabel.Text = '数字图像处理APP';
            % Show the figure after all components are created
            app.IPApplicationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageProcessingApplication1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.IPApplicationUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IPApplicationUIFigure)
        end
    end
end